classdef song_converter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        Button                 matlab.ui.control.Button           % Select ...
        ButtonGroup            matlab.ui.container.ButtonGroup    % What wo...
        RadioButton            matlab.ui.control.RadioButton      % Remove ...
        RadioButton2           matlab.ui.control.RadioButton      % Lower f...
        RadioButton3           matlab.ui.control.RadioButton      % nothere
        Panel                  matlab.ui.container.Panel          % Select ...
        LabelNumericEditField  matlab.ui.control.Label            % Type sp...
        NumericEditField       matlab.ui.control.NumericEditField % [-Inf Inf]
        Label                  matlab.ui.control.Label            % OR
        LabelNumericEditField2 matlab.ui.control.Label            % Type ra...
        NumericEditField2      matlab.ui.control.NumericEditField % [-Inf Inf]
        Panel2                 matlab.ui.container.Panel          % Select ...
        CheckBox               matlab.ui.control.CheckBox         % Select ...
        LabelNumericEditField3 matlab.ui.control.Label            % Select ...
        NumericEditField3      matlab.ui.control.NumericEditField % [-Inf Inf]
        Label2                 matlab.ui.control.Label            % Select ...
        NumericEditField4      matlab.ui.control.NumericEditField % [-Inf Inf]
        Label3                 matlab.ui.control.Label            % OR
    end


    properties (Access = private)
        Y 
        FS
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.Panel.Visible = 'off';
           app.Panel2.Visible = 'off';
           app.RadioButton3.Visible = 'off';
        end

        % Button button pushed function
        function ButtonPushed(app)
            [file,path] = uigetfile('*.wav'); %open a wav file
            [y,fs] = audioread(file);
            x = size(y);
            z = x(1,2);
            app.Y = y;
            app.FS = fs;
            
            if z == 1           %test if the song is stereo
                f = errordlg('Try another file in stereo','File Error')
            end

         
        end

        % ButtonGroup selection change function
        function ButtonGroupSelectionChanged(app, event)
          %  selectedButton = app.ButtonGroup.SelectedObject;
          if app.RadioButton2.Value == true
              app.Panel.Visible = 'on';
          else
               app.Panel.Visible = 'off';
          end
          
          if app.RadioButton.Value == true
              left = app.Y(:,1);
              right = app.Y(:,2);
             % soundsc(left-right,app.FS)
             unvoiced = left - right;
             [file,path] = uiputfile('*.wav');
             audiowrite(file,unvoiced,app.FS)
             
          end
        end

        % NumericEditField3 value changed function
        function NumericEditField3ValueChanged(app)
            value3 = app.NumericEditField3.Value;
            
        end

        % NumericEditField value changed function
        function NumericEditFieldValueChanged(app)
            value1 = app.NumericEditField.Value;
            x = exist('value1');
            if x == 1
                app.Panel2.Visible = 'on'
            end
            
        end

        % NumericEditField2 value changed function
        function NumericEditField2ValueChanged(app)
            value2 = app.NumericEditField2.Value;
            y = exist('value2');
            if y == 1
                app.Panel2.Visible = 'on'
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed);
            app.Button.Text = 'Select song (stereo)';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'What would you want to apply?';
            app.ButtonGroup.Units = 'pixels';
            app.RadioButton = uiradiobutton(app.ButtonGroup);
            app.RadioButton.Text = 'Remove vocals';
            app.RadioButton2 = uiradiobutton(app.ButtonGroup);
            app.RadioButton2.Text = 'Lower frecuencies';
            app.RadioButton3 = uiradiobutton(app.ButtonGroup);
            app.RadioButton3.Value = true;
            app.RadioButton3.Text = 'nothere';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Select frequency/ies';
            app.Panel.Units = 'pixels';
            app.LabelNumericEditField = uilabel(app.Panel);
            app.LabelNumericEditField.Text = 'Type specific frequency';
            app.NumericEditField = uieditfield(app.Panel, 'numeric');
            app.NumericEditField.ValueChangedFcn = createCallbackFcn(app, @NumericEditFieldValueChanged);
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'OR';
            app.LabelNumericEditField2 = uilabel(app.Panel);
            app.LabelNumericEditField2.Text = 'Type range of frequencies';
            app.NumericEditField2 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField2.ValueChangedFcn = createCallbackFcn(app, @NumericEditField2ValueChanged);
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Select time';
            app.Panel2.Units = 'pixels';
            app.CheckBox = uicheckbox(app.Panel2);
            app.CheckBox.Text = 'Select the whole song';
            app.LabelNumericEditField3 = uilabel(app.Panel2);
            app.LabelNumericEditField3.Text = 'Select starting time in secs';
            app.NumericEditField3 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField3.ValueChangedFcn = createCallbackFcn(app, @NumericEditField3ValueChanged);
            app.Label2 = uilabel(app.Panel2);
            app.Label2.Text = 'Select ending time in secs';
            app.NumericEditField4 = uieditfield(app.Panel2, 'numeric');
            app.Label3 = uilabel(app.Panel2);
            app.Label3.Text = 'OR';
        end
    end

    methods (Access = public)

        % Construct app
        function app = song_converter()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end